classdef electric_field < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimularButton                   matlab.ui.control.Button
        AEditFieldLabel                 matlab.ui.control.Label
        AEditField                      matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        DistanceBetweenPlatesEditFieldLabel  matlab.ui.control.Label
        DistanceBetweenPlatesEditField  matlab.ui.control.NumericEditField
        SizeofeachplateLabel            matlab.ui.control.Label
        AEditField_2Label               matlab.ui.control.Label
        AEditField_2                    matlab.ui.control.NumericEditField
        AEditField_2Label_2             matlab.ui.control.Label
        BEditField_2                    matlab.ui.control.NumericEditField
        ChargeConfigurationLabel        matlab.ui.control.Label
        SelectUnitsDropDownLabel        matlab.ui.control.Label
        SelectUnitsDropDown             matlab.ui.control.DropDown
        PlatesConfigurationLabel        matlab.ui.control.Label
        PlateLabel                      matlab.ui.control.Label
        PlateLabel_2                    matlab.ui.control.Label
        PlateLabel_3                    matlab.ui.control.Label
        PlateLabel_4                    matlab.ui.control.Label
        SizeofeachplateLabel_2          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TwoPlatesElectricFieldSimulatorLabel  matlab.ui.control.Label
        F1013BLabel                     matlab.ui.control.Label
        SizeofeachplateLabel_3          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
                n_1 = app.AEditField.Value;
                n_2 = app.BEditField.Value;
                d = app.DistanceBetweenPlatesEditField.Value;
                k = 9*10e9;
                
                units = app.SelectUnitsDropDown.Value;
                
                Q_1c = 0;
                Q_2c = 0;
                
                %CREAMOS CAMPO 
                x = -10:0.5:10;
                y = -10:0.5:10;
                
                [X, Y] = meshgrid(x, y);
                
                E_X = X; %zeros([41, 41])
                E_Y = Y; %zeros([41, 41])
                
                counter_P1 = -n_1/2;
                while counter_P1 < n_1/2
                    Q_1p = [-d/2, counter_P1]; 
                    Q_1c = Q_1c + app.AEditField_2.Value * units;   %0.3*10^(-6); 
                    
                    %VECTOR R para las carga 1
                    R1_x = X - Q_1p(1);
                    R1_y = Y - Q_1p(2);
                    R1= sqrt((R1_x).^2 + (R1_y).^2);
                
                    %Campo Electrico para componente X, Y
                    E1_x = ((k * Q_1c)./(R1.^3)) .* R1_x;
                    E1_y = ((k * Q_1c)./(R1.^3)) .* R1_y;
                    
                    E_X = E_X + E1_x;
                    E_Y = E_Y + E1_y;
                    
                    counter_P1 = counter_P1 + 0.5;
                end 
                
                
                counter_P2 = -n_2/2;
                while counter_P2 < n_2/2
                    Q_2p = [d/2, counter_P2]; 
                    Q_2c = Q_2c +app.BEditField_2.Value * units;  
                    
                    %VECTOR R para las carga 2
                    R2_x = X - Q_2p(1);
                    R2_y = Y - Q_2p(2);
                    R2 = sqrt((R2_x).^2 + (R2_y).^2);
                
                    %Campo Electrico para componente X, Y
                    E2_x = ((k * Q_2c)./(R2.^3)) .* R2_x;
                    E2_y = ((k * Q_2c)./(R2.^3)) .* R2_y;
                
                    
                    E_X = E_X + E2_x;
                    E_Y = E_Y + E2_y;
                    
                    counter_P2 = counter_P2 + 0.5;
                end 
                
                U = E_X./sqrt(E_X.^2+E_Y.^2);
                V = E_Y./sqrt(E_X.^2+E_Y.^2);
                
                
                quiver(app.UIAxes,X, Y, U, V, 'k');
                axis(app.UIAxes, [-10 10 -10 10]);
                
                color_A = "#1E90FF";
                color_B = "#1E90FF";
                
                if Q_1c > 0
                    color_A = "#E2062C";
                end
                
                if Q_2c > 0
                    color_B = "#E2062C";
                end

                h=rectangle(app.UIAxes, 'Position',[-(d/2)-.25,-n_1/2,.5,n_1]);
                set(h,'Facecolor',	color_A ,'Edgecolor', color_A);
                
                h=rectangle(app.UIAxes, 'Position',[(d/2)-.25,-n_2/2,.5,n_2]);
                set(h,'Facecolor', color_B,'Edgecolor', color_B);
        end

        % Callback function
        function AEditFieldValueChanged(app, event)
            value = app.AEditField.Value;
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A:';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B:';
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceBetweenPlatesEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceBetweenPlatesEditFieldLabel.Text = 'Distance Between Plates';
            app.DistanceBetweenPlatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeofeachplateLabel = uilabel(app.UIFigure);
            app.SizeofeachplateLabel.Text = 'Indicate the length of each plate. The plates will be automatically alligned to the center of their length (L/2).';
            app.AEditField_2Label = uilabel(app.UIFigure);
            app.AEditField_2Label.Text = 'A:';
            app.AEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AEditField_2Label_2 = uilabel(app.UIFigure);
            app.AEditField_2Label_2.Text = 'B:';
            app.BEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ChargeConfigurationLabel = uilabel(app.UIFigure);
            app.ChargeConfigurationLabel.Text = 'Charge Configuration';
            app.SelectUnitsDropDownLabel = uilabel(app.UIFigure);
            app.SelectUnitsDropDownLabel.Text = 'Select Units';
            app.SelectUnitsDropDown = uidropdown(app.UIFigure);
            app.SelectUnitsDropDown.Items = {'μC', 'nC', 'C'};
            app.SelectUnitsDropDown.ItemsData = [1e-06 1e-09 1];
            app.SelectUnitsDropDown.Value = 1e-06;
            app.PlatesConfigurationLabel = uilabel(app.UIFigure);
            app.PlatesConfigurationLabel.Text = 'Plates Configuration';
            app.PlateLabel = uilabel(app.UIFigure);
            app.PlateLabel.Text = 'Plate';
            app.PlateLabel_2 = uilabel(app.UIFigure);
            app.PlateLabel_2.Text = 'Plate';
            app.PlateLabel_3 = uilabel(app.UIFigure);
            app.PlateLabel_3.Text = 'Plate';
            app.PlateLabel_4 = uilabel(app.UIFigure);
            app.PlateLabel_4.Text = 'Plate';
            app.SizeofeachplateLabel_2 = uilabel(app.UIFigure);
            app.SizeofeachplateLabel_2.Text = 'Indicate the charge per 0.1 distance unit [dQ] ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'itesm_logo.png';
            app.TwoPlatesElectricFieldSimulatorLabel = uilabel(app.UIFigure);
            app.TwoPlatesElectricFieldSimulatorLabel.Text = 'Two Plates Electric Field Simulator';
            app.F1013BLabel = uilabel(app.UIFigure);
            app.F1013BLabel.Text = 'F1013B';
            app.SizeofeachplateLabel_3 = uilabel(app.UIFigure);
            app.SizeofeachplateLabel_3.Text = {'*Red - Positive Charged '; ' Blue - Negative Charged'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Electric Field')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electric_field

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end